classdef neurofreq < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UITable3                       matlab.ui.control.Table
        UITable2                       matlab.ui.control.Table
        BLMethodDropDown               matlab.ui.control.DropDown
        BLMethodDropDownLabel          matlab.ui.control.Label
        baselinetimesEditFieldLabel    matlab.ui.control.Label
        baselinetimesEditField         matlab.ui.control.EditField
        freqsofinterestEditField       matlab.ui.control.EditField
        freqsofinterestEditFieldLabel  matlab.ui.control.Label
        timesofinterestEditField       matlab.ui.control.EditField
        timesofinterestEditFieldLabel  matlab.ui.control.Label
        SaveAverageBaselineButton      matlab.ui.control.Button
        SaveSingleTrialButton          matlab.ui.control.Button
        SaveTFLabel                    matlab.ui.control.Label
        PlotAverageBaselineButton      matlab.ui.control.Button
        PlotSingleTrialButton          matlab.ui.control.Button
        VisualizeTFLabel               matlab.ui.control.Label
        AverageBaselineTFSettingLabel  matlab.ui.control.Label
        DoTFButton                     matlab.ui.control.Button
        ExecuteTransformLabel          matlab.ui.control.Label
        PrepDataButton                 matlab.ui.control.Button
        RemoveTrendsandApplyTaperOptionalLabel  matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
        MethodDropDownLabel            matlab.ui.control.Label
        TFSettingsLabel                matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        LoadButton                     matlab.ui.control.Button
        filenameEditField              matlab.ui.control.EditField
        filenameEditFieldLabel         matlab.ui.control.Label
        LoadEEGLABsetfileLabel         matlab.ui.control.Label
        BrowseButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        EEG % EEGLAB .set
        method % TFmethod
        blmethod = 'none'; % Baseline method
        TF % TF
        TF_bl % baselined/averaged TF
        toi % times of interest
        foi % freqs of interest
        bl_times % baseline times
        tfh % history of commands
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BrowseButton, UITable
        function BrowseButtonPushed(app, event)
            [filename,filepath] = uigetfile('*.set');
            app.filenameEditField.Value = [filepath filesep filename];
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.EEG = pop_loadset(app.filenameEditField.Value);
            app.tfh{1} = {'pop_loadset(filename);'};
            app.EEG = pop_select(app.EEG,'channel',find(strcmp({app.EEG.chanlocs.type},'EEG')));
            app.tfh{2} = {'pop_select(EEG,"channel",find(strcmp({app.EEG.chanlocs.type},"EEG")));'};
            %set table values
            app.UITable.Data = {app.EEG.nbchan app.EEG.pnts app.EEG.trials app.EEG.srate};
            %set toi, foi, and baseline times
            value = ['[' num2str(app.EEG.xmin) ' ' num2str(app.EEG.xmax) ']'];
            app.timesofinterestEditField.Value = value;
            app.toi = value;
            value = ['[1 ' num2str(floor(app.EEG.srate/4)) ']'];
            app.freqsofinterestEditField.Value = value;
            app.foi = value;
            value = ['[' num2str(app.EEG.xmin) ' 0]'];
            app.baselinetimesEditField.Value = value;
            app.bl_times = value;
        end

        % Button pushed function: PrepDataButton
        function PrepDataButtonPushed(app, event)
            app.EEG = nf_prepData( app.EEG );
            app.tfh{3} = {'EEG = nf_prepData(EEG);'};
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
            app.method = value;
            switch value
                case 'stft'
                    app.UITable3.Data = {.5, 80, 1};
                    app.UITable3.ColumnName = {'window','overlap','fRes'};
                case 'filterhilbert'
                    app.UITable3.Data = {2, 3, []};
                    app.UITable3.ColumnName = {'fBandWidth','order',''};
                case 'demodulation'
                    app.UITable3.Data = {2, 3, []};
                    app.UITable3.ColumnName = {'lowPassF','order',''};
                case 'wavelet'
                    app.UITable3.Data = {4, [], []};
                    app.UITable3.ColumnName = {'cycles','',''};
                case 'cwt'
                    app.UITable3.Data = {[], [], []};
                    app.UITable3.ColumnName = {'','',''};
                case 'stransform'
                    app.UITable3.Data = {[], [], []};
                    app.UITable3.ColumnName = {'','',''};
                case 'ridbinomial'
                    nTimes = size(app.EEG.data,2);
                    fout = linspace(0,app.EEG.srate/2,nTimes);
                    fRes = mean(diff(fout));
                    kernel = 2*nTimes;
                    app.UITable3.Data = {fRes, kernel, 0};
                    app.UITable3.ColumnName = {'fRes','kernel','makePos'};
                case 'ridbornjordan'
                    nTimes = size(app.EEG.data,2);
                    fout = linspace(0,app.EEG.srate/2,nTimes);
                    fRes = mean(diff(fout));
                    kernel = 2*nTimes;
                    app.UITable3.Data = {fRes, kernel, 0};
                    app.UITable3.ColumnName = {'fRes','kernel','makePos'};
                case 'ridrihaczek'
                    nTimes = size(app.EEG.data,2);
                    fout = linspace(0,app.EEG.srate/2,nTimes);
                    fRes = mean(diff(fout));
                    kernel = 2*nTimes;
                    app.UITable3.Data = {fRes, kernel, 0};
                    app.UITable3.ColumnName = {'fRes','kernel','makePos'};
            end
        end

        % Button pushed function: DoTFButton
        function DoTFButtonPushed(app, event)
            if ~isempty(app.UITable3.Data{3})
                app.TF = nf_tfTransform(app.EEG,'method',app.method,'freqs',str2num(app.foi),'times',str2num(app.toi), ...
                    app.UITable3.ColumnName{1}, app.UITable3.Data{1},...
                    app.UITable3.ColumnName{2}, app.UITable3.Data{2},...
                    app.UITable3.ColumnName{3}, app.UITable3.Data{3});
                tfString = {['TF = nf_tfTransform(EEG,"method", "' app.method '","freqs",' app.foi ', "times", ' app.toi ', "' ...
                    app.UITable3.ColumnName{1} '", ' num2str(app.UITable3.Data{1}) ', "' ...
                    app.UITable3.ColumnName{2} '", ' num2str(app.UITable3.Data{2}) ', "' ...
                    app.UITable3.ColumnName{3} '", ' num2str(app.UITable3.Data{3}) '));']};
            elseif ~isempty(app.UITable3.Data{2})
                app.TF = nf_tfTransform(app.EEG,'method',app.method,'freqs',str2num(app.foi),'times',str2num(app.toi), ...
                    app.UITable3.ColumnName{1}, app.UITable3.Data{1},...
                    app.UITable3.ColumnName{2}, app.UITable3.Data{2});
                tfString = {['TF = nf_tfTransform(EEG,"method", "' app.method '","freqs",' app.foi ', "times", ' app.toi ', "' ...
                    app.UITable3.ColumnName{1} '", ' num2str(app.UITable3.Data{1}) ', "' ...
                    app.UITable3.ColumnName{2} '", ' num2str(app.UITable3.Data{2}) '));']};
            elseif ~isempty(app.UITable3.Data{1})
                app.TF = nf_tfTransform(app.EEG,'method',app.method,'freqs',str2num(app.foi),'times',str2num(app.toi), ...
                    app.UITable3.ColumnName{1}, app.UITable3.Data{1});
                tfString = {['TF = nf_tfTransform(EEG,"method", "' app.method '","freqs",' app.foi ', "times", ' app.toi ', "' ...
                    app.UITable3.ColumnName{1} '", ' num2str(app.UITable3.Data{1}) '));']};
            else
                app.TF = nf_tfTransform(app.EEG,'method',app.method,'freqs',str2num(app.foi),'times',str2num(app.toi));
                tfString = {['TF = nf_tfTransform(EEG,"method", "' app.method '","freqs",' app.foi ', "times", ' app.toi '));']};
            end
            app.tfh{4} = tfString;
            app.TF_bl = nf_aveBase(app.TF,app.blmethod,str2num(app.bl_times));
            app.tfh{5} = {['TF_bl = nf_aveBase(EEG,"method",' app.blmethod ', ' app.bl_times ');']};
            app.TF.history = app.tfh;
            app.TF_bl.history = app.tfh;
            app.UITable2.Data = {app.TF.nsensor numel(app.TF.freqs) numel(app.TF.times) app.TF.ntrls app.TF.Fs};
        end

        % Button pushed function: PlotSingleTrialButton
        function PlotSingleTrialButtonPushed(app, event)
            nf_viewerApp(app.TF);
        end

        % Button pushed function: PlotAverageBaselineButton
        function PlotAverageBaselineButtonPushed(app, event)
            nf_viewerApp(app.TF_bl);
        end

        % Button pushed function: SaveSingleTrialButton
        function SaveSingleTrialButtonPushed(app, event)
            [filep,filen] = fileparts(app.filenameEditField.Value);
            TF = app.TF;
            disp('saving single trials...');
            save([filep [filen '_TF.mat']],'TF');
        end

        % Button pushed function: SaveAverageBaselineButton
        function SaveAverageBaselineButtonPushed(app, event)
            [filep,filen] = fileparts(app.filenameEditField.Value);
            TF_bl = app.TF_bl;
            disp('saving averaged/baselined data...');
            save([filep filesep [filen '_TF_avbl.mat']],'TF_bl');            
        end

        % Value changed function: timesofinterestEditField
        function timesofinterestEditFieldValueChanged(app, event)
            value = app.timesofinterestEditField.Value;
            app.toi = value;
        end

        % Value changed function: freqsofinterestEditField
        function freqsofinterestEditFieldValueChanged(app, event)
            value = app.freqsofinterestEditField.Value;
            app.foi = value;
        end

        % Value changed function: baselinetimesEditField
        function baselinetimesEditFieldValueChanged(app, event)
            value = app.baselinetimesEditField.Value;
            app.bl_times = value;
        end

        % Value changed function: BLMethodDropDown
        function BLMethodDropDownValueChanged(app, event)
            value = app.BLMethodDropDown.Value;
            app.blmethod = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.LoadEEGLABsetfileLabel = uilabel(app.UIFigure);
            app.LoadEEGLABsetfileLabel.Text = 'Load EEGLAB .set file:';
            app.filenameEditFieldLabel = uilabel(app.UIFigure);
            app.filenameEditFieldLabel.Text = 'filename';
            app.filenameEditField = uieditfield(app.UIFigure, 'text');
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'nChannels'; 'nTimes'; 'nTrials'; 'Fs'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.TFSettingsLabel = uilabel(app.UIFigure);
            app.TFSettingsLabel.Text = '2) TF Settings:';
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'(pick one!)', 'stft', 'filterhilbert', 'demodulation', 'cwt', 'wavelet', 'stransform', 'ridbinomial', 'ridbornjordan', 'ridrihaczek'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = '(pick one!)';
            app.RemoveTrendsandApplyTaperOptionalLabel = uilabel(app.UIFigure);
            app.RemoveTrendsandApplyTaperOptionalLabel.Text = '1) Remove Trends and Apply Taper (Optional):';
            app.PrepDataButton = uibutton(app.UIFigure, 'push');
            app.PrepDataButton.ButtonPushedFcn = createCallbackFcn(app, @PrepDataButtonPushed, true);
            app.PrepDataButton.Text = 'Prep Data';
            app.ExecuteTransformLabel = uilabel(app.UIFigure);
            app.ExecuteTransformLabel.Text = '4) Execute Transform:';
            app.DoTFButton = uibutton(app.UIFigure, 'push');
            app.DoTFButton.ButtonPushedFcn = createCallbackFcn(app, @DoTFButtonPushed, true);
            app.DoTFButton.Text = 'Do TF!';
            app.AverageBaselineTFSettingLabel = uilabel(app.UIFigure);
            app.AverageBaselineTFSettingLabel.Text = '3) Average/Baseline TF Setting:';
            app.VisualizeTFLabel = uilabel(app.UIFigure);
            app.VisualizeTFLabel.Text = '5) Visualize TF:';
            app.PlotSingleTrialButton = uibutton(app.UIFigure, 'push');
            app.PlotSingleTrialButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSingleTrialButtonPushed, true);
            app.PlotSingleTrialButton.Text = 'Plot Single-Trial';
            app.PlotAverageBaselineButton = uibutton(app.UIFigure, 'push');
            app.PlotAverageBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAverageBaselineButtonPushed, true);
            app.PlotAverageBaselineButton.Text = 'Plot Average/Baseline';
            app.SaveTFLabel = uilabel(app.UIFigure);
            app.SaveTFLabel.Text = '6) Save TF:';
            app.SaveSingleTrialButton = uibutton(app.UIFigure, 'push');
            app.SaveSingleTrialButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSingleTrialButtonPushed, true);
            app.SaveSingleTrialButton.Text = 'Save Single-Trial';
            app.SaveAverageBaselineButton = uibutton(app.UIFigure, 'push');
            app.SaveAverageBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAverageBaselineButtonPushed, true);
            app.SaveAverageBaselineButton.Text = 'Save Average/Baseline';
            app.timesofinterestEditFieldLabel = uilabel(app.UIFigure);
            app.timesofinterestEditFieldLabel.Text = 'times of interest';
            app.timesofinterestEditField = uieditfield(app.UIFigure, 'text');
            app.timesofinterestEditField.ValueChangedFcn = createCallbackFcn(app, @timesofinterestEditFieldValueChanged, true);
            app.timesofinterestEditField.Value = 'times in seconds here [-.5 1]';
            app.freqsofinterestEditFieldLabel = uilabel(app.UIFigure);
            app.freqsofinterestEditFieldLabel.Text = 'freqs of interest';
            app.freqsofinterestEditField = uieditfield(app.UIFigure, 'text');
            app.freqsofinterestEditField.ValueChangedFcn = createCallbackFcn(app, @freqsofinterestEditFieldValueChanged, true);
            app.freqsofinterestEditField.Value = 'frequency vector here (1:1:30)';
            app.baselinetimesEditField = uieditfield(app.UIFigure, 'text');
            app.baselinetimesEditField.ValueChangedFcn = createCallbackFcn(app, @baselinetimesEditFieldValueChanged, true);
            app.baselinetimesEditField.Value = 'times in s';
            app.baselinetimesEditFieldLabel = uilabel(app.UIFigure);
            app.baselinetimesEditFieldLabel.Text = 'baseline times';
            app.BLMethodDropDownLabel = uilabel(app.UIFigure);
            app.BLMethodDropDownLabel.Text = 'BL Method';
            app.BLMethodDropDown = uidropdown(app.UIFigure);
            app.BLMethodDropDown.Items = {'(pick one!)', 'none', 'db', 'percent', 'zscore'};
            app.BLMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @BLMethodDropDownValueChanged, true);
            app.BLMethodDropDown.Value = '(pick one!)';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'nChannels'; 'nFreqs'; 'nTimes'; 'nTrials'; 'Fs'};
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnEditable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = neurofreq

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end