classdef nf_viewerapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        GoToSensorMax               matlab.ui.control.Button
        GoToGlobalMax               matlab.ui.control.Button
        datapointvalue              matlab.ui.control.Label
        text9                       matlab.ui.control.Label
        uipanel2                    matlab.ui.container.Panel
        tf_pickelectrode            matlab.ui.control.DropDown
        topo_electrodetype          matlab.ui.control.DropDown
        topo_showselectedelectrode  matlab.ui.control.CheckBox
        topo_clim                   matlab.ui.control.EditField
        text6                       matlab.ui.control.Label
        topo_plotrad                matlab.ui.control.EditField
        text5                       matlab.ui.control.Label
        topo_numcontour             matlab.ui.control.EditField
        text1                       matlab.ui.control.Label
        uipanel1                    matlab.ui.container.Panel
        tf_clim                     matlab.ui.control.EditField
        text2                       matlab.ui.control.Label
        tf_trialM                   matlab.ui.control.Button
        tf_trialP                   matlab.ui.control.Button
        tf_trial2plot               matlab.ui.control.EditField
        text13                      matlab.ui.control.Label
        tf_freqM                    matlab.ui.control.Button
        tf_freqP                    matlab.ui.control.Button
        tf_timeM                    matlab.ui.control.Button
        tf_timeP                    matlab.ui.control.Button
        tf_freq2plot                matlab.ui.control.EditField
        text4                       matlab.ui.control.Label
        tf_time2plot                matlab.ui.control.EditField
        text3                       matlab.ui.control.Label
        tf_freqscaling              matlab.ui.control.DropDown
        tf_showcrosshairs           matlab.ui.control.CheckBox
        uipanel3                    matlab.ui.container.ButtonGroup
        GoToSensorMin               matlab.ui.control.Button
        GoToGlobalMin               matlab.ui.control.Button
        tf_axes_3                   matlab.ui.control.UIAxes
        tf_axes_2                   matlab.ui.control.UIAxes
        topo_axes                   matlab.ui.control.UIAxes
        tf_axes                     matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function [handle, pltchans, epos] = topoplotX(~, Values, chanlocs, varargin)

            %% Set defaults

            headrad = 0.5;          % actual head radius - Don't change this!
            GRID_SCALE = 67;        % plot map on a 67X67 grid
            CIRCGRID   = 201;       % number of angles to use in drawing circles
            HEADCOLOR = [0 0 0];    % default head color (black)
            HLINEWIDTH = 1.7;         % default linewidth for head, nose, ears
            BLANKINGRINGWIDTH = .035;% width of the blanking ring
            HEADRINGWIDTH    = .007;% width of the cartoon head ring

            nargs = nargin;
            if nargs > 3
                for i = 1:2:length(varargin)
                    Param = lower(varargin{i});
                    Value = varargin{i+1};
                    switch Param
                        case 'numcontour'
                            CONTOURNUM = Value;
                        case 'electrodes'
                            ELECTRODES = lower(Value);
                        case 'plotrad'
                            plotrad = Value;
                        case 'shading'
                            SHADING = lower(Value);
                            if ~any(strcmp(SHADING,{'flat','interp'}))
                                error('Invalid shading parameter')
                            end
                    end
                end
            end

            Values = Values(:); % make Values a column vector

            %% Read channel location
            labels={chanlocs.labels};
            Th=[chanlocs.theta];
            Rd=[chanlocs.radius];

            Th = pi/180*Th;                              % convert degrees to radians
            allchansind = 1:length(Th);
            plotchans = 1:length(chanlocs);

            %% remove infinite and NaN values

            inds = union(find(isnan(Values)), find(isinf(Values))); % NaN and Inf values
            inds = union(inds,find(isempty([chanlocs.X])));

            plotchans   = setdiff(plotchans,inds);

            [x,y]       = pol2cart(Th,Rd);  % transform electrode locations from polar to cartesian coordinates
            plotchans   = abs(plotchans);   % reverse indicated channel polarities
            allchansind = allchansind(plotchans);
            Rd          = Rd(plotchans);
            x           = x(plotchans);
            y           = y(plotchans);
            labels      = char(labels(plotchans)); % remove labels for electrodes without locations
            Values      = Values(plotchans);
            intrad      = min(1.0,max(Rd)*1.02);             % default: just outside the outermost electrode location

            %% Find plotting channels
            pltchans = find(Rd <= plotrad); % plot channels inside plotting circle
            intchans = find(x <= intrad & y <= intrad); % interpolate and plot channels inside interpolation square

            %% Eliminate channels not plotted

            allchansind = allchansind(pltchans);
            intx  = x(intchans);
            inty  = y(intchans);
            x     = x(pltchans);
            y     = y(pltchans);

            intValues = Values(intchans);

            labels= labels(pltchans,:);

            %% Squeeze channel locations to <= headrad
            squeezefac = headrad/plotrad;
            % to plot all inside the head cartoon
            intx  = intx*squeezefac;
            inty  = inty*squeezefac;
            x     = x*squeezefac;
            y     = y*squeezefac;

            %% create grid
            xmin = min(-headrad,min(intx)); xmax = max(headrad,max(intx));
            ymin = min(-headrad,min(inty)); ymax = max(headrad,max(inty));
            xi   = linspace(xmin,xmax,GRID_SCALE);   % x-axis description (row vector)
            yi   = linspace(ymin,ymax,GRID_SCALE);   % y-axis description (row vector)

            [Xi,Yi,Zi] = griddata(inty,intx,intValues,yi',xi,'v4'); % interpolate data

            %% Mask out data outside the head
            mask = (sqrt(Xi.^2 + Yi.^2) <= headrad); % mask outside the plotting circle
            Zi(mask == 0) = NaN;                  % mask non-plotting voxels with NaNs
            delta = xi(2)-xi(1); % length of grid entry

            %% Scale the axes and make the plot
            cla  % clear current axis
            hold on
            AXHEADFAC = 1.05;     % do not leave room for external ears if head cartoon
            set(gca,'Xlim',[-headrad headrad]*AXHEADFAC,'Ylim',[-headrad headrad]*AXHEADFAC);
            unsh = (GRID_SCALE+1)/GRID_SCALE; % un-shrink the effects of 'interp' SHADING

            if strcmp(SHADING,'interp')
                handle = surface(Xi*unsh,Yi*unsh,zeros(size(Zi)),Zi,'EdgeColor','none','FaceColor',SHADING);
            else
                handle = surface(Xi-delta/2,Yi-delta/2,zeros(size(Zi)),Zi,'EdgeColor','none','FaceColor',SHADING);
            end
            contour(Xi,Yi,Zi,CONTOURNUM,'k','hittest','off');

            %% Plot filled ring to mask jagged grid boundary
            hwidth = HEADRINGWIDTH;                   % width of head ring
            hin  = squeezefac*headrad*(1- hwidth/2);  % inner head ring radius

            if strcmp(SHADING,'interp')
                rwidth = BLANKINGRINGWIDTH*1.3;             % width of blanking outer ring
            else
                rwidth = BLANKINGRINGWIDTH;         % width of blanking outer ring
            end
            rin    =  headrad*(1-rwidth/2);              % inner ring radius
            if hin>rin
                rin = hin;                              % dont blank inside the head ring
            end

            circ = linspace(0,2*pi,CIRCGRID);
            rx = sin(circ);
            ry = cos(circ);
            ringx = [[rx(:)' rx(1) ]*(rin+rwidth)  [rx(:)' rx(1)]*rin];
            ringy = [[ry(:)' ry(1) ]*(rin+rwidth)  [ry(:)' ry(1)]*rin];
            patch(ringx,ringy,0.01*ones(size(ringx)),get(gcf,'color'),'edgecolor','none','hittest','off'); hold on

            %% Plot cartoon head, ears, nose
            headx = [[rx(:)' rx(1) ]*(hin+hwidth)  [rx(:)' rx(1)]*hin];
            heady = [[ry(:)' ry(1) ]*(hin+hwidth)  [ry(:)' ry(1)]*hin];
            patch(headx,heady,ones(size(headx)),HEADCOLOR,'edgecolor',HEADCOLOR,'hittest','off'); hold on

            % Plot ears and nose
            base  = headrad-.0046;
            basex = 0.18*headrad;                   % nose width
            tip   = 1.15*headrad;
            tiphw = .04*headrad;                    % nose tip half width
            tipr  = .01*headrad;                    % nose tip rounding
            q = .04; % ear lengthening
            EarX  = [.497-.005  .510  .518  .5299 .5419  .54    .547   .532   .510   .489-.005]; % headrad = 0.5
            EarY  = [q+.0555 q+.0775 q+.0783 q+.0746 q+.0555 -.0055 -.0932 -.1313 -.1384 -.1199];
            sf    = headrad/plotrad;                                          % squeeze the model ears and nose
            % by this factor
            plot3([basex;tiphw;0;-tiphw;-basex]*sf,[base;tip-tipr;tip;tip-tipr;base]*sf,2*ones(size([basex;tiphw;0;-tiphw;-basex])),'Color',HEADCOLOR,'LineWidth',HLINEWIDTH,'hittest','off');                 % plot nose
            plot3(EarX*sf,EarY*sf,2*ones(size(EarX)),'color',HEADCOLOR,'LineWidth',HLINEWIDTH,'hittest','off')    % plot left ear
            plot3(-EarX*sf,EarY*sf,2*ones(size(EarY)),'color',HEADCOLOR,'LineWidth',HLINEWIDTH,'hittest','off')   % plot right ear

            %% Mark electrode locations
            if strcmp(ELECTRODES,'on')   % plot electrodes as spots
                plot3(y,x,ones(size(x)),'.','Color',[0 0 0],'markersize',5,'linewidth',.5,'hittest','off');
            elseif strcmp(ELECTRODES,'labels')  % print electrode names (labels)
                text(double(y),double(x),{chanlocs(pltchans).labels},'HorizontalAlignment','center','VerticalAlignment','middle','Color',[0 0 0],'hittest','off')
            elseif strcmp(ELECTRODES,'numbers')
                for i = 1:size(labels,1)
                    h = text(double(y(i)),double(x(i)),1,int2str(allchansind(i)),'HorizontalAlignment','center','VerticalAlignment','middle','Color',[0 0 0]);%,'hittest','off')
                    set(h,'hittest','off');
                end
            end

            epos=[x; y];
            axis off
            axis equal
        end

        function [handle, pltchans, epos] = topoplotrect(~, Values, chanlocs,varargin)

            %% Set defaults

            headrad = 0.5;          % actual head radius - Don't change this!
            GRID_SCALE = 67;        % plot map on a 67X67 grid
            CIRCGRID   = 201;       % number of angles to use in drawing circles
            HEADCOLOR = [0 0 0];    % default head color (black)
            HLINEWIDTH = 1.7;         % default linewidth for head, nose, ears
            BLANKINGRINGWIDTH = .035;% width of the blanking ring
            HEADRINGWIDTH    = .007;% width of the cartoon head ring

            nargs = nargin;
            if nargs > 3
                for i = 1:2:length(varargin)
                    Param = lower(varargin{i});
                    Value = varargin{i+1};
                    switch Param
                        case 'numcontour'
                            CONTOURNUM = Value;
                        case 'electrodes'
                            ELECTRODES = lower(Value);
                        case 'plotrad'
                            plotrad = Value;
                        case 'shading'
                            SHADING = lower(Value);
                            if ~any(strcmp(SHADING,{'flat','interp'}))
                                error('Invalid shading parameter')
                            end
                    end
                end
            end
            Values = Values(:); % make Values a column vector

            %% Read channel location
            labels={chanlocs.labels};
            allchansind = 1:length(chanlocs);
            plotchans = 1:length(chanlocs);
            pltchans = plotchans;
            x = [chanlocs.X];
            y = [chanlocs.Y];

            %% create grid
            xi   = linspace(min(x),max(x),GRID_SCALE);   % x-axis description (row vector)
            yi   = linspace(min(y),max(y),GRID_SCALE);   % y-axis description (row vector)
            [Xi,Yi,Zi] = griddata(y,x,Values,yi',xi,'v4'); % interpolate data

            %% Scale the axes and make the plot
            cla  % clear current axis
            hold on   
            unsh = (GRID_SCALE+1)/GRID_SCALE; % un-shrink the effects of 'interp' SHADING
            handle = surface(Xi*unsh,Yi*unsh,zeros(size(Zi)),Zi,'EdgeColor','none','FaceColor','interp');
            contour(Xi,Yi,Zi,5,'k','hittest','off');

            %% Mark electrode locations
            if strcmp(ELECTRODES,'on')   % plot electrodes as spots
                plot3(y,x,ones(size(x)),'.','Color',[0 0 0],'markersize',5,'linewidth',.5,'hittest','off');
            elseif strcmp(ELECTRODES,'labels')  % print electrode names (labels)
                text(double(y),double(x),{chanlocs(pltchans).labels},'HorizontalAlignment','center','VerticalAlignment','middle','Color',[0 0 0],'hittest','off')
            elseif strcmp(ELECTRODES,'numbers')
                for i = 1:size(labels,1)
                    h = text(double(y(i)),double(x(i)),1,int2str(allchansind(i)),'HorizontalAlignment','center','VerticalAlignment','middle','Color',[0 0 0]);%,'hittest','off')
                    set(h,'hittest','off');
                end
            end

            epos=[x; y];
            axis off
            axis equal
        end
        
        function update_tfmap(~, handles)
            data=get(handles.figure1,'UserData'); t=data.t; f=data.f; ctf=data.ctf; trl=size(data.ctf,4); chanlocs=data.chanlocs;
            % get TF point
            time2plot = str2double(get(handles.tf_time2plot,'String'));
            freq2plot = str2double(get(handles.tf_freq2plot,'String'));
            trial2plot = str2double(get(handles.tf_trial2plot,'String'));
            title(handles.tf_axes,[ 'Electrode ' chanlocs(get(handles.tf_pickelectrode,'Value')).labels ' (#' num2str(get(handles.tf_pickelectrode,'Value')) '), '...
                'Trial #' num2str(trial2plot) ]);

            %fix out-of-bounds values
            if time2plot>t(end)
                time2plot=t(end);
                set(handles.tf_time2plot,'string',num2str(time2plot));
            end
            if freq2plot>f(end)
                freq2plot=f(end);
                set(handles.tf_freq2plot,'string',num2str(freq2plot));
            end
            if trial2plot>trl
                trial2plot=trl;
                set(handles.tf_trial2plot,'string',num2str(trial2plot));
            end

%             if time2plot>t(end), time2plot=t(end); end
%             if time2plot<t(1),   time2plot=t(1);   end
            if trial2plot>trl, trial2plot=trl; end
            if trial2plot<1,   trial2plot=1;   end
            
            %make contour plot
            [~,handles.tfobject]=contourf(handles.tf_axes,t,f,squeeze(ctf(get(handles.tf_pickelectrode,'Value'),:,:,trial2plot)),40,'linecolor','none','HitTest',"off");
            colorbar(handles.tf_axes);
            
            %if stats - plot contour outline
            if isfield(data,'statflag')
                [~,handles.tfobject]=contour(handles.tf_axes,t,f,squeeze(data.mask(get(handles.tf_pickelectrode,'Value'),:,:,trial2plot)),1,'linewidth',4,'color','k','HitTest',"off");
            end
            
            %make marginal plots
            plot(handles.tf_axes2,squeeze(mean(ctf(get(handles.tf_pickelectrode,'Value'),:,:,trial2plot),3)),f,'linewidth',5);
            axis(handles.tf_axes2, 'tight');
            title(handles.tf_axes2,'power spectrum');
            plot(handles.tf_axes3,t,squeeze(mean(ctf(get(handles.tf_pickelectrode,'Value'),:,:,trial2plot),2)),'linewidth',5);
            axis(handles.tf_axes3, 'tight');
            title(handles.tf_axes3,'power time series');
            
            % update crosshairs
            handles.tflineV=plot(handles.tf_axes,[str2double(get(handles.tf_time2plot,'String')) str2double(get(handles.tf_time2plot,'String'))],get(handles.tf_axes,'ylim'),'k','linewidth',3,'Visible',handles.tf_showcrosshairs.Value);
            handles.tflineH=plot(handles.tf_axes,get(handles.tf_axes,'xlim'),[str2double(get(handles.tf_freq2plot,'String')) str2double(get(handles.tf_freq2plot,'String'))],'k','linewidth',3,'Visible',handles.tf_showcrosshairs.Value);

            clim = sscanf(get(handles.tf_clim,'String'),'%g');
            if length(clim)==1, clim=[-abs(clim) abs(clim)];end
            if isempty(clim), clim=[-3 3]; end
            if length(clim)>2,  clim=clim(1:2); end
            set(handles.tf_axes,'clim',clim);

            yskip=5;
            if get(handles.tf_freqscaling,'Value')==1
                set(handles.tf_axes,'yscale','log','ytick',round(logspace(log10(f(1)),log10(f(end)),length(f(1:yskip:end)))*10)/10);
                set(handles.tf_axes2,'yscale','log','ytick',round(logspace(log10(f(1)),log10(f(end)),length(f(1:yskip:end)))*10)/10);
            else
                set(handles.tf_axes,'yscale','linear','ytick',round(linspace(f(1),f(end),length(f(1:yskip:end)))*10)/10);
                set(handles.tf_axes2,'yscale','linear','ytick',round(linspace(f(1),f(end),length(f(1:yskip:end)))*10)/10);
            end
            set(handles.tfobject,'buttondownfcn',{@tf_axes_ButtonDownFcn,handles});
        end

        function handles = update_topomap(app, handles)
            % plot topoplot
            data=get(handles.figure1,'UserData'); t=data.t; f=data.f; ctf=data.ctf; trial=1:size(data.ctf,4); chanlocs=data.chanlocs;
            set(handles.figure1,'CurrentAxes',handles.topo_axes)
            if get(handles.topo_showselectedelectrode,'Value')==1; end
            toposhading='interp';

            etype='labels';
            switch get(handles.topo_electrodetype,'value')
                case 1, etype = 'off';
                case 2, etype = 'on';
                case 3, etype = 'labels';
                case 4, etype = 'numbers';
            end

            [~,fpoint] = min(abs(f-str2double(get(handles.tf_freq2plot,'string'))));
            [~,tpoint] = min(abs(t-str2double(get(handles.tf_time2plot,'string'))));
            [~,trlpoint] = min(abs(trial-str2double(get(handles.tf_trial2plot,'string'))));
            if data.pillow==1
                [handles.topoobject,handles.pltchans,handles.epos] = topoplotrect(app, squeeze(ctf(:,fpoint,tpoint,trlpoint)),chanlocs,'plotrad',str2double(get(handles.topo_plotrad,'string')),'numcontour',str2double(get(handles.topo_numcontour,'string')),'electrodes',etype,'shading',toposhading);
            else
                [handles.topoobject,handles.pltchans,handles.epos] = topoplotX(app, squeeze(ctf(:,fpoint,tpoint,trlpoint)),chanlocs,'plotrad',str2double(get(handles.topo_plotrad,'string')),'numcontour',str2double(get(handles.topo_numcontour,'string')),'electrodes',etype,'shading',toposhading);
            end
            % set color limits
            topoclim = sscanf(get(handles.topo_clim,'string'),'%g');
            if numel(topoclim)==1, topoclim=[-abs(topoclim) abs(topoclim)]; end
            set(handles.topo_axes,'clim',topoclim);
            % plot selected electrode
            e2plot = handles.pltchans==get(handles.tf_pickelectrode,'value');
            if any(e2plot)
                handles.selected_electrode = plot3(handles.epos(2,e2plot),handles.epos(1,e2plot),1,'ko','linew',2,'markerface','m','hittest','off');
                if get(handles.topo_showselectedelectrode,'value')==1
                    set(handles.selected_electrode,'visible','on');
                else
                    set(handles.selected_electrode,'visible','off');
                end
            end
            set(handles.topoobject,'buttondownfcn',{@topo_axes_ButtonDownFcn,handles});

            title(handles.topo_axes,[ get(handles.tf_time2plot,'string') ' ms, ' get(handles.tf_freq2plot,'string') ' Hz, ' ,...
                'trial #' get(handles.tf_trial2plot,'string')]);

            % update TFS point
            set(handles.datapointvalue,'string',num2str(ctf(get(handles.tf_pickelectrode,'value'),fpoint,tpoint,trlpoint)))

            %turn off hit test
            set(handles.topoobject,'HitTest',0);

            % set colorbar
            colorbar(handles.topo_axes);

            % Update handles structure
            guidata(handles.figure1,handles);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function nf_viewerapp_OpeningFcn(app, varargin)

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to tfviewerx (see VARARGIN)

            % Choose default command line output for tfViewer
            handles.output = hObject;

            % check inputs
            if nargin<1
                error('Requires TF as input.')
            else
                TF            = varargin{1};
                data.t        = TF.times*1000;
                data.f        = TF.freqs;
                if length(varargin)>=2
                    if strcmp(varargin{2},'power')==1
                        if isfield(TF,'nsubjects')
                            data.ctf = squeeze(mean(TF.power));
                        else
                            data.ctf = TF.power;
                        end
                    elseif strcmp(varargin{2},'phase')==1
                        if isfield(TF,'nsubjects')
                            data.ctf = squeeze(mean(TF.phase));
                        else
                            data.ctf = TF.phase;
                        end
                    elseif strcmp(varargin{2}, 'shap')==1
                        if isfield(TF,'nsubjects')
                            data.ctf = squeeze(mean(TF.SHAP(:,:,:,:)));
                        else
                            data.ctf = TF.SHAP(:,:,:,:);
                        end
                    elseif strcmp(varargin{2},'sprint')==1
                        if isfield(TF,'nsubjects')
                            sprint1 = squeeze(mean(TF.SPRiNT.ap_power));
                            sprint2 = squeeze(mean(TF.SPRiNT.osc_power));
                            ct = cat(4,sprint1,sprint2);
                        else
                            ct = cat(4,TF.SPRiNT.ap_power,TF.SPRiNT.osc_power);
                        end
                        data.ctf = ct;
                    elseif strcmp(varargin{2},'cpm')==1
                        if isfield(TF,'nsubjects')
                            cpm1 = squeeze(mean(TF.cpm.PLmean));
                            cpm2 = squeeze(mean(TF.cpm.NPLmean));
                            ct = cat(4,cpm1,cpm2);
                        else
                            ct = cat(4,TF.cpm.PLmean,TF.cpm.NPLmean);
                        end
                        data.ctf = ct;
                    elseif strcmp(varargin{2},'erp')
                        if isfield(TF,'nsubjects')
                            cpm1 = squeeze(mean(TF.erprem.erprempow));
                            cpm2 = squeeze(mean(TF.erprem.erppow));
                            ct = cat(4,cpm1,cpm2);
                        else
                            ct = cat(4,TF.erprem.erprempow,TF.erprem.erppow);
                        end
                        data.ctf = ct;
                    else
                        error('second argument must be power, phase, cpm, sprint, erp, shap');
                    end
                else
                    if isfield(TF,'nsubjects') && isfield(TF,'power')
                        data.ctf = squeeze(mean(TF.power));
                    elseif isfield(TF,'tstat')
                        data.ctf = TF.tstat;
                        data.mask = TF.p_vals<.05;
                        data.statflag = 1;
                    else
                        data.ctf = TF.power;
                    end
                end
                if isfield(TF,'custommontage')
                    if strcmp(TF.custommontage,'pillow64')
                        data.pillow = 1;
                    end
                else
                    data.pillow = 0;
                end
                data.trls = size(data.ctf,4);
                data.chanlocs = TF.chanlocs;
                if length(varargin)==3
                    colormap(varargin{3});
                else
                    colormap('parula');
                end
            end
            
            %set color limits
            dlim = max(abs([prctile(data.ctf(:),1) prctile(data.ctf(:),99)]));
            set(handles.tf_clim,'string',num2str([-1*dlim dlim]));
            set(handles.topo_clim,'string',num2str([-1*dlim dlim]));

            %automatically set plot radius based on # channels
            if length(data.chanlocs)>100
                set(handles.topo_plotrad,'string','.75');
            else
                set(handles.topo_plotrad,'string','.55');
            end

            %set/get user data
            set(handles.figure1,'UserData',data);
            data=get(handles.figure1,'UserData'); t=data.t; f=data.f; ctf=data.ctf;

            %% plot data
            set(handles.tf_pickelectrode,'String',{data.chanlocs.labels});

            % plot TF
            yskip=round(numel(f)/20);
            [~,handles.tfobject]=contourf(handles.tf_axes,t,f,squeeze(ctf(get(handles.tf_pickelectrode,'Value'),:,:,1)),40,'linecolor','none','HitTest',"off");
            colorbar(handles.tf_axes);
            set(handles.tf_axes,'yscale','log','ytick',round(logspace(log10(f(1)),log10(f(end)),length(f(1:yskip:end)))*100)/100);
            hold(handles.tf_axes,'on')
            
            %if stats - plot contour outline
            if isfield(data,'statflag')
                [~,handles.tfobject]=contour(handles.tf_axes,t,f,squeeze(data.mask(get(handles.tf_pickelectrode,'Value'),:,:,1)),1,'linewidth',4,'color','k','HitTest',"off");
            end

            %plot crosshairs
            handles.tflineV=plot(handles.tf_axes,[str2double(get(handles.tf_time2plot,'String')) str2double(get(handles.tf_time2plot,'String'))],get(handles.tf_axes,'ylim'),'k','linewidth',3,'Visible',handles.tf_showcrosshairs.Value);
            handles.tflineH=plot(handles.tf_axes,get(handles.tf_axes,'xlim'),[str2double(get(handles.tf_freq2plot,'String')) str2double(get(handles.tf_freq2plot,'String'))],'k','linewidth',3,'Visible',handles.tf_showcrosshairs.Value);

            % plot topo
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
            update_tfmap(app, handles);

            % Update handles structure
            guidata(handles.figure1,handles);

        end

        % Button pushed function: GoToGlobalMax
        function GoToGlobalMax_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            data=get(handles.figure1,'UserData'); t=data.t; f=data.f; ctf=data.ctf;
            % find max point and convert to TFE indices
            [~,maxpoint]  = max(ctf(:));
            [maxE,maxF,maxT,maxTrl] = ind2sub(size(ctf),maxpoint);
            % update
            set(handles.tf_freq2plot,'string',num2str(round(f(maxF)*10)/10));
            set(handles.tf_time2plot,'string',num2str(round(t(maxT))));
            set(handles.tf_trial2plot,'string',maxTrl);
            set(handles.tf_pickelectrode,'value',maxE);
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
            update_tfmap(app, handles);

        end

        % Button pushed function: GoToGlobalMin
        function GoToGlobalMin_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            data=get(handles.figure1,'UserData'); t=data.t; f=data.f; ctf=data.ctf;
            % find max point and convert to TFE indices
            [~,minpoint]  = min(ctf(:));
            [minE,minF,minT,minTrl] = ind2sub(size(ctf),minpoint);
            % update
            set(handles.tf_freq2plot,'string',num2str(round(f(minF)*10)/10));
            set(handles.tf_time2plot,'string',num2str(round(t(minT))));
            set(handles.tf_trial2plot,'string',minTrl);
            set(handles.tf_pickelectrode,'value',minE);
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
            update_tfmap(app, handles);

        end

        % Button pushed function: GoToSensorMax
        function GoToSensorMax_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            data=get(handles.figure1,'UserData'); t=data.t; f=data.f; ctf=data.ctf;
            % find max point and convert to TFE indices
            ctf=squeeze(ctf(get(handles.tf_pickelectrode,'Value'),:,:,:));
            [~,maxpoint]  = max(ctf(:));
            [maxF,maxT,maxTrl] = ind2sub(size(ctf),maxpoint);
            % update
            set(handles.tf_freq2plot,'string',num2str(round(f(maxF)*10)/10));
            set(handles.tf_time2plot,'string',num2str(round(t(maxT))));
            set(handles.tf_trial2plot,'string',num2str(maxTrl))
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
            update_tfmap(app, handles);

        end

        % Button pushed function: GoToSensorMin
        function GoToSensorMin_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            data=get(handles.figure1,'UserData'); t=data.t; f=data.f; ctf=data.ctf;
            % find max point and convert to TFE indices
            ctf=squeeze(ctf(get(handles.tf_pickelectrode,'Value'),:,:,:));
            [~,minpoint]  = min(ctf(:));
            [minF,minT,minTrl] = ind2sub(size(ctf),minpoint);
            % update
            set(handles.tf_freq2plot,'string',num2str(round(f(minF)*10)/10));
            set(handles.tf_time2plot,'string',num2str(round(t(minT))));
            set(handles.tf_trial2plot,'string',num2str(minTrl));
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
            update_tfmap(app, handles);

        end

        % Button down function: tf_axes
        function tf_axes_ButtonDownFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            xy = get(handles.tf_axes,'Currentpoint');
            x=xy(1,1); y=xy(1,2);
            set(handles.tflineV,'xdata',[x x],'ydata',get(handles.tf_axes,'ylim'),'hittest','off');
            set(handles.tflineH,'xdata',get(handles.tf_axes,'xlim'),'ydata',[y y],'hittest','off');
            % update TF displays
            set(handles.tf_time2plot,'String',num2str(round(x)));
            set(handles.tf_freq2plot,'String',num2str(round(y)));
            enames=get(handles.tf_pickelectrode,'String');
            title(handles.tf_axes,[ 'Electrode ' enames{get(handles.tf_pickelectrode,'Value')} ' (#' num2str(get(handles.tf_pickelectrode,'Value')) ')' ]);
            % plot topoplot
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);

        end

        % Value changed function: tf_clim
        function tf_clim_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            update_tfmap(app, handles);
        end

        % Value changed function: tf_freq2plot
        function tf_freq2plotValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            data=get(handles.figure1,'UserData'); f=data.f;
            currf=str2double(get(handles.tf_freq2plot,'string'));
            if currf<f(1), currf=f(1);
            elseif currf>f(end), currf=f(end);
            end
            [~,f_ind] = min(abs(f-currf)); 
            set(handles.tf_freq2plot,'string',num2str(f(f_ind)));
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
            update_tfmap(app, handles)
        end

        % Button pushed function: tf_freqM
        function tf_freqMButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            data=get(handles.figure1,'UserData'); f=data.f;
            [~,currf]=min(abs(f-str2double(get(handles.tf_freq2plot,'string'))));
            currf=max(1,currf-1);
            set(handles.tf_freq2plot,'string',num2str(round(f(currf)*10)/10));
            update_tfmap(app, handles);
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
        end

        % Button pushed function: tf_freqP
        function tf_freqPButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            data=get(handles.figure1,'UserData'); f=data.f;
            [~,currf]=min(abs(f-str2double(get(handles.tf_freq2plot,'string'))));
            currf=min(length(f),currf+1);
            set(handles.tf_freq2plot,'string',num2str(round(f(currf)*10)/10));
            update_tfmap(app, handles);
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
        end

        % Value changed function: tf_freqscaling
        function tf_freqscaling_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            update_tfmap(app, handles);
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
        end

        % Value changed function: tf_pickelectrode
        function tf_pickelectrode_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            update_tfmap(app, handles);
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
        end

        % Value changed function: tf_time2plot
        function tf_time2plotValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            data=get(handles.figure1,'UserData'); t=data.t;
            [~,currt]=min(abs(t-str2double(get(handles.tf_time2plot,'string'))));
            set(handles.tf_time2plot,'string',t(currt));
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
            update_tfmap(app, handles)
            guidata(handles.figure1,handles);
        end

        % Button pushed function: tf_timeM
        function tf_timeMButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            data=get(handles.figure1,'UserData'); t=data.t;
            td=mean(diff(t));
            set(handles.tf_time2plot,'string',num2str(round(str2double(get(handles.tf_time2plot,'string'))-td)));
            update_tfmap(app, handles);
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
        end

        % Button pushed function: tf_timeP
        function tf_timePButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            data=get(handles.figure1,'UserData'); t=data.t;
            td=mean(diff(t));
            set(handles.tf_time2plot,'string',num2str(round(str2double(get(handles.tf_time2plot,'string'))+td)));
            update_tfmap(app, handles);
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
        end

        % Callback function
        function tf_xlim_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU
            update_tfmap(app, handles);
        end

        % Button down function: topo_axes
        function topo_axes_ButtonDownFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            data=get(handles.figure1,'UserData'); chanlocs=data.chanlocs;
            xy = get(handles.topo_axes,'Currentpoint');
            x=xy(1,1); y=xy(1,2);
            nearest_electrode = dsearchn(handles.epos',[y x]);
            ctemp = chanlocs(handles.pltchans);
            set(handles.tf_pickelectrode,'value',find(strcmpi(get(handles.tf_pickelectrode,'string'),ctemp(nearest_electrode).labels)));
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
            update_tfmap(app, handles);
        end

        % Value changed function: topo_clim
        function topo_clim_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
        end

        % Value changed function: topo_electrodetype
        function topo_electrodetype_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
        end

        % Value changed function: topo_numcontour
        function topo_numcontour_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
        end

        % Value changed function: topo_plotrad
        function topo_plotrad_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
        end

        % Callback function
        function topo_plottype_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
        end

        % Value changed function: topo_showselectedelectrode
        function topo_showselectedelectrode_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
        end

        % Value changed function: tf_trial2plot
        function tf_trial2plotValueChanged(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            data=get(handles.figure1,'UserData'); trls=size(data.ctf,4);
            if str2double(get(handles.tf_trial2plot,'string'))<trls
                set(handles.tf_trial2plot,'string',1);
            elseif str2double(get(handles.tf_trial2plot,'string'))>trls
                set(handles.tf_trial2plot,'string',trls);
            end
            update_tfmap(app, handles);
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
            guidata(handles.figure1,handles);
        end

        % Button pushed function: tf_trialP
        function tf_trialPButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            set(handles.tf_trial2plot,'string',num2str(str2double(get(handles.tf_trial2plot,'string'))+1));
            update_tfmap(app, handles);
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
        end

        % Button pushed function: tf_trialM
        function tf_trialMButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            set(handles.tf_trial2plot,'string',num2str(str2double(get(handles.tf_trial2plot,'string'))-1));
            update_tfmap(app, handles);
            handles=update_topomap(app, handles); guidata(handles.figure1,handles);
        end

        % Value changed function: tf_showcrosshairs
        function tf_showcrosshairsValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            value = app.tf_showcrosshairs.Value;
            if value==1
                set(handles.tflineV,'visible','on');
                set(handles.tflineH,'visible','on');
            else
                set(handles.tflineV,'visible','off');
                set(handles.tflineH,'visible','off');
            end
            update_tfmap(app, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'tfViewer';
            app.figure1.Tag = 'figure1';
            app.tf_axes = uiaxes(app.figure1);
            app.tf_axes.ButtonDownFcn = createCallbackFcn(app, @tf_axes_ButtonDownFcn, true);
            app.tf_axes.Tag = 'tf_axes';
            app.topo_axes = uiaxes(app.figure1);
            app.topo_axes.ButtonDownFcn = createCallbackFcn(app, @topo_axes_ButtonDownFcn, true);
            app.topo_axes.Tag = 'topo_axes';
            app.tf_axes_2 = uiaxes(app.figure1);
            app.tf_axes_2.Tag = 'tf_axes2';
            app.tf_axes_3 = uiaxes(app.figure1);
            app.tf_axes_3.Tag = 'tf_axes3';
            app.uipanel3 = uibuttongroup(app.figure1);
            app.uipanel3.Title = 'Data values';
            app.uipanel3.Tag = 'uipanel3';
            app.GoToGlobalMin = uibutton(app.uipanel3, 'push');
            app.GoToGlobalMin.ButtonPushedFcn = createCallbackFcn(app, @GoToGlobalMin_Callback, true);
            app.GoToGlobalMin.Tag = 'GoToGlobalMin';
            app.GoToGlobalMin.Text = 'Global min';
            app.GoToSensorMin = uibutton(app.uipanel3, 'push');
            app.GoToSensorMin.ButtonPushedFcn = createCallbackFcn(app, @GoToSensorMin_Callback, true);
            app.GoToSensorMin.Tag = 'GoToSensorMin';
            app.GoToSensorMin.Text = 'Sensor min';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Time-frequency plot options';
            app.uipanel1.Tag = 'uipanel1';
            app.tf_showcrosshairs = uicheckbox(app.uipanel1);
            app.tf_showcrosshairs.ValueChangedFcn = createCallbackFcn(app, @tf_showcrosshairsValueChanged, true);
            app.tf_showcrosshairs.Tag = 'tf_showcrosshairs';
            app.tf_showcrosshairs.Text = 'Show crosshairs';
            app.tf_showcrosshairs.Value = true;
            app.tf_freqscaling = uidropdown(app.uipanel1);
            app.tf_freqscaling.Items = {'Log frequency', 'Linear frequency'};
            app.tf_freqscaling.ValueChangedFcn = createCallbackFcn(app, @tf_freqscaling_Callback, true);
            app.tf_freqscaling.Tag = 'tf_freqscaling';
            app.tf_freqscaling.Value = 'Linear frequency';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Time (ms)';
            app.tf_time2plot = uieditfield(app.uipanel1, 'text');
            app.tf_time2plot.ValueChangedFcn = createCallbackFcn(app, @tf_time2plotValueChanged, true);
            app.tf_time2plot.Tag = 'tf_time2plot';
            app.tf_time2plot.Value = '300';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Frequency (in Hz)';
            app.tf_freq2plot = uieditfield(app.uipanel1, 'text');
            app.tf_freq2plot.ValueChangedFcn = createCallbackFcn(app, @tf_freq2plotValueChanged, true);
            app.tf_freq2plot.Tag = 'tf_freq2plot';
            app.tf_freq2plot.Value = '6';
            app.tf_timeP = uibutton(app.uipanel1, 'push');
            app.tf_timeP.ButtonPushedFcn = createCallbackFcn(app, @tf_timePButtonPushed, true);
            app.tf_timeP.Tag = 'tf_timeP';
            app.tf_timeP.Text = '+';
            app.tf_timeM = uibutton(app.uipanel1, 'push');
            app.tf_timeM.ButtonPushedFcn = createCallbackFcn(app, @tf_timeMButtonPushed, true);
            app.tf_timeM.Tag = 'tf_timeM';
            app.tf_timeM.Text = '-';
            app.tf_freqP = uibutton(app.uipanel1, 'push');
            app.tf_freqP.ButtonPushedFcn = createCallbackFcn(app, @tf_freqPButtonPushed, true);
            app.tf_freqP.Tag = 'tf_freqP';
            app.tf_freqP.Text = '+';
            app.tf_freqM = uibutton(app.uipanel1, 'push');
            app.tf_freqM.ButtonPushedFcn = createCallbackFcn(app, @tf_freqMButtonPushed, true);
            app.tf_freqM.Tag = 'tf_freqM';
            app.tf_freqM.Text = '-';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Tag = 'text11';
            app.text13.Text = 'Trial #';
            app.tf_trial2plot = uieditfield(app.uipanel1, 'text');
            app.tf_trial2plot.ValueChangedFcn = createCallbackFcn(app, @tf_trial2plotValueChanged, true);
            app.tf_trial2plot.Tag = 'tf_trial2plot';
            app.tf_trial2plot.Value = '1';
            app.tf_trialP = uibutton(app.uipanel1, 'push');
            app.tf_trialP.ButtonPushedFcn = createCallbackFcn(app, @tf_trialPButtonPushed, true);
            app.tf_trialP.Tag = 'tf_trialP';
            app.tf_trialP.Text = '+';
            app.tf_trialM = uibutton(app.uipanel1, 'push');
            app.tf_trialM.ButtonPushedFcn = createCallbackFcn(app, @tf_trialMButtonPushed, true);
            app.tf_trialM.Tag = 'tf_trialM';
            app.tf_trialM.Text = '-';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Color limits';
            app.tf_clim = uieditfield(app.uipanel1, 'text');
            app.tf_clim.ValueChangedFcn = createCallbackFcn(app, @tf_clim_Callback, true);
            app.tf_clim.Tag = 'tf_clim';
            app.tf_clim.Value = '-3 3';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Topomap options';
            app.uipanel2.Tag = 'uipanel2';
            app.text1 = uilabel(app.uipanel2);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Num contour lines';
            app.topo_numcontour = uieditfield(app.uipanel2, 'text');
            app.topo_numcontour.ValueChangedFcn = createCallbackFcn(app, @topo_numcontour_Callback, true);
            app.topo_numcontour.Tag = 'topo_numcontour';
            app.topo_numcontour.Value = '3';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Plot radius';
            app.topo_plotrad = uieditfield(app.uipanel2, 'text');
            app.topo_plotrad.ValueChangedFcn = createCallbackFcn(app, @topo_plotrad_Callback, true);
            app.topo_plotrad.Tag = 'topo_plotrad';
            app.topo_plotrad.Value = '0.55';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Color limit';
            app.topo_clim = uieditfield(app.uipanel2, 'text');
            app.topo_clim.ValueChangedFcn = createCallbackFcn(app, @topo_clim_Callback, true);
            app.topo_clim.Tag = 'topo_clim';
            app.topo_clim.Value = '3';
            app.topo_showselectedelectrode = uicheckbox(app.uipanel2);
            app.topo_showselectedelectrode.ValueChangedFcn = createCallbackFcn(app, @topo_showselectedelectrode_Callback, true);
            app.topo_showselectedelectrode.Tag = 'topo_showselectedelectrode';
            app.topo_showselectedelectrode.Text = 'Show selected electrode';
            app.topo_showselectedelectrode.Value = true;
            app.topo_electrodetype = uidropdown(app.uipanel2);
            app.topo_electrodetype.Items = {'No electrodes', 'Dots', 'Labels', 'Numbers'};
            app.topo_electrodetype.ValueChangedFcn = createCallbackFcn(app, @topo_electrodetype_Callback, true);
            app.topo_electrodetype.Tag = 'topo_electrodetype';
            app.topo_electrodetype.Value = 'Labels';
            app.tf_pickelectrode = uidropdown(app.uipanel2);
            app.tf_pickelectrode.Items = {'Pick an electrode'};
            app.tf_pickelectrode.ValueChangedFcn = createCallbackFcn(app, @tf_pickelectrode_Callback, true);
            app.tf_pickelectrode.Tag = 'tf_pickelectrode';
            app.tf_pickelectrode.Value = 'Pick an electrode';
            app.text9 = uilabel(app.figure1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Value at selection';
            app.datapointvalue = uilabel(app.figure1);
            app.datapointvalue.Tag = 'datapointvalue';
            app.datapointvalue.Text = 'Static Text';
            app.GoToGlobalMax = uibutton(app.figure1, 'push');
            app.GoToGlobalMax.ButtonPushedFcn = createCallbackFcn(app, @GoToGlobalMax_Callback, true);
            app.GoToGlobalMax.Tag = 'GoToGlobalMax';
            app.GoToGlobalMax.Text = 'Global max';
            app.GoToSensorMax = uibutton(app.figure1, 'push');
            app.GoToSensorMax.ButtonPushedFcn = createCallbackFcn(app, @GoToSensorMax_Callback, true);
            app.GoToSensorMax.Tag = 'GoToSensorMax';
            app.GoToSensorMax.Text = 'Sensor max';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nf_viewerapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)nf_viewerapp_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end